classdef Ultrasound_simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RF_app              matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        Button              matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        VEditFieldLabel     matlab.ui.control.Label
        VEditField          matlab.ui.control.NumericEditField
        sEditFieldLabel     matlab.ui.control.Label
        sEditField          matlab.ui.control.NumericEditField
        cmEditFieldLabel    matlab.ui.control.Label
        cmEditField         matlab.ui.control.NumericEditField
        cmEditField_2Label  matlab.ui.control.Label
        cmEditField_2       matlab.ui.control.EditField
        RightPanel          matlab.ui.container.Panel
        UIAxes              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RF_app.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {306, 306};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% ターゲットサイズ
            val = app.DropDown.Value;
            switch val
                case {'大'}
                    t_s = 1;
                case {'中'}
                    t_s = 0.5;
                case {'小'}
                    t_s = 0.25;        
            end
            %% 平均回数
            val2 = app.DropDown_2.Value;
            switch val2
                case {'なし'}
                    a_n = 1;
                case {'2回'}
                    a_n = 2;
                case {'4回'}
                    a_n = 4;
                case {'8回'}
                    a_n = 8;
                case {'16回'}
                    a_n = 16;                
            end
            %% フレームレートの計算・表示
            app.sEditField.Value = 100/a_n;
            
            % 平均　% ノイズ生成
            N = zeros(5*10^4,a_n);
            for i = 1:a_n
                N(:,i) = wgn(5*10^4,1,0);
            end
            Noise = mean(N,2);
            
            %% 信号とノイズの合成
            d = 0.6; % 距離
            t1(:,1) = 1:2400;
            S1(:,1) = 10*sin(2*pi*12/length(t1).*t1).*tukeywin(length(t1),1);
            S2 = zeros(1,5*10^4);
            d2 = 5*fix(d*2/340*10^6);
            S2(:,d2+1:d2+200*12) = S1.';
            
            RF = t_s*S2 + Noise.';
            t = 0:1/5000:10-1/5000;
            
            %% 閾値の検出
            t_t = app.VEditField.Value;
            
            i2 = find(RF > t_t,1);
            if isempty(i2)
                tof = 0;
            else
                tof = i2/5000;
            end

            plot(app.UIAxes,t,RF,"b",[t(1) t(end)],[t_t(1) t_t(end)],"r");
            legend(app.UIAxes,{'出力波形','閾値'},'Location','Best')
            xlim(app.UIAxes,[3.2 4.4])
            ylim(app.UIAxes,[-15 15])
            
            %% 距離の計算
            if tof == 0
                app.cmEditField_2.Value = "障害物なし";
            else
                app.cmEditField_2.Value = num2str(round(340*tof/20,3));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RF_app = uifigure('Visible', 'off');
            app.RF_app.Name = 'Ultrasound_simulator';
            app.RF_app.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RF_app);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '実行';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '障害物のサイズ';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'大', '中', '小'};
            app.DropDown.Value = '大';
            app.DropDown_2Label = uilabel(app.LeftPanel);
            app.DropDown_2Label.Text = '平均回数';
            app.DropDown_2 = uidropdown(app.LeftPanel);
            app.DropDown_2.Items = {'なし', '2回', '4回', '8回', '16回'};
            app.DropDown_2.Value = 'なし';
            app.VEditFieldLabel = uilabel(app.LeftPanel);
            app.VEditFieldLabel.Text = '障害物検出の閾値 [V]';
            app.VEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VEditField.Limits = [0 15];
            app.sEditFieldLabel = uilabel(app.LeftPanel);
            app.sEditFieldLabel.Text = 'フレームレート [/s]';
            app.sEditField = uieditfield(app.LeftPanel, 'numeric');
            app.sEditField.Editable = 'off';
            app.cmEditFieldLabel = uilabel(app.LeftPanel);
            app.cmEditFieldLabel.Text = '実際の距離 [cm]';
            app.cmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.cmEditField.Editable = 'off';
            app.cmEditField.Value = 60;
            app.cmEditField_2Label = uilabel(app.LeftPanel);
            app.cmEditField_2Label.Text = '検出距離 [cm]';
            app.cmEditField_2 = uieditfield(app.LeftPanel, 'text');
            app.cmEditField_2.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '超音波センサの出力')
            xlabel(app.UIAxes, '時間 [ms]')
            ylabel(app.UIAxes, '出力電圧 [V]')
            app.UIAxes.YLim = [-15 15];
            % Show the figure after all components are created
            app.RF_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ultrasound_simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RF_app)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RF_app)
        end
    end
end